classdef AccelController < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        StepperAcceleration           matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        LeftPanel                     matlab.ui.container.Panel
        StepsToRun                    matlab.ui.control.Spinner
        SpeedradminLabel_2            matlab.ui.control.Label
        RestEncoderButton             matlab.ui.control.Button
        SpeedRateSpinner              matlab.ui.control.Spinner
        SpeedradminLabel              matlab.ui.control.Label
        AccelerationRatseSpinner      matlab.ui.control.Spinner
        AccelerationradminLabel       matlab.ui.control.Label
        SamplingRateSpinner           matlab.ui.control.Spinner
        SamplingratemsLabel           matlab.ui.control.Label
        MessurementDrationSpinner     matlab.ui.control.Spinner
        MessureTimemsLabel            matlab.ui.control.Label
        StartMessureButton            matlab.ui.control.Button
        MicrosteppingDropDown         matlab.ui.control.DropDown
        MicrosteppingLabel            matlab.ui.control.Label
        ManualRunButton               matlab.ui.control.StateButton
        DirectionSwitch               matlab.ui.control.Switch
        DirectionSwitchLabel          matlab.ui.control.Label
        StepperEnableSwitch           matlab.ui.control.Switch
        StepperEnableSwitchLabel      matlab.ui.control.Label
        StateLamp                     matlab.ui.control.Lamp
        StateLampLabel                matlab.ui.control.Label
        RightPanel                    matlab.ui.container.Panel
        TabGroup                      matlab.ui.container.TabGroup
        PlotTab                       matlab.ui.container.Tab
        CurrentEncoderPostionSpinner  matlab.ui.control.Spinner
        CurrentEncoderPostionSpinner_2Label_2  matlab.ui.control.Label
        RemaingTimeforMessureSpinner  matlab.ui.control.Spinner
        RemaingTimeforMessureSpinnerLabel  matlab.ui.control.Label
        CurrentEncoderValueSpinner    matlab.ui.control.Spinner
        CurrentEncoderValueSpinnerLabel  matlab.ui.control.Label
        CurrentStepperPostionSpinner  matlab.ui.control.Spinner
        CurrentStepperPostionSpinnerLabel  matlab.ui.control.Label
        PlotPanel                     matlab.ui.container.Panel
        UIAxes                        matlab.ui.control.UIAxes
        SettingsTab                   matlab.ui.container.Tab
        GridLayout2                   matlab.ui.container.GridLayout
        StepperStepsPerTurnSpinner    matlab.ui.control.Spinner
        StepperstepersperTurnLabel    matlab.ui.control.Label
        EncoderStepsPerTurnSpinner    matlab.ui.control.Spinner
        EncoderstepersperTurnLabel    matlab.ui.control.Label
        FileMenu                      matlab.ui.container.Menu
        ModbusInterfaceMenu           matlab.ui.container.Menu
        StreamingInterfaceMenu        matlab.ui.container.Menu
        ExportCXVMenu                 matlab.ui.container.Menu
        ImportCSVMenu                 matlab.ui.container.Menu
        ExitMenu                      matlab.ui.container.Menu
        EditMenu                      matlab.ui.container.Menu
        ClearPlotMenu                 matlab.ui.container.Menu
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    properties( Constant = true, Hidden = true )
        MB_PORT                           =     'COM2';  % Serial port of Modbus
        STREAM_PORT                       =     'COM4';  % Serial Streaming Port
        MB_COIL_STEPPER_ENA               =     1      % stepper direction 0=CW; 1=CCW 
        MB_COIL_STEPPER_DIR_CCW           =     2      % Enables the stepper
        MB_COIL_STEPPER_RUN               =     3      % Run the stepper 
        MB_COIL_STEPPER_MOVE              =     4      % Run the stepper clockwise
        MB_COIL_START_MEASSURE            =     5      % Start one measurement cycle
        MB_COIL_ZERO_ENCODER              =     6      % sets the encoder to zero        
        MB_NUM_COILS                      =     6  

        MB_CONTACT_STEPPER_IS_RUNNING     =     1      % indicates weather the stepper is running
        MB_CONTACT_STEPPER_IS_STOPPING    =     2      % indicates weather the stepper is stopping
        MB_CONTACT_MESSUREMENT_IS_RUNNING =     3      % indicates weather the stepper is stopping
        MB_NUM_CONTACTS                   =     3

        MB_INPUT_SYSTEM_TIME              =     1      % current system Time (LSB)
        MB_INPUT_SYSTEM_TIME_START        =     5      % current system Time at start (LSB)
        MB_INPUT_SYSTEM_TIME_FINISH       =     9      % current system Time at finish (LSB)
        MB_INPUT_STEPPER_STEPS            =    13      % Current steps from the stepper driver
        MB_INPUT_ENCODER_STEPS            =    17      % current steps from the encoder
        MB_INPUT_ENCODER_ANGLE            =    21      % current (calculated) Angle from the encoder [rad*10000]
        MB_NUM_INPUTS                     =    21
           
        MB_HOLDING_STEPPER_TURN_STEPS     =     1      % stepper: amout of steps per turn
        MB_HOLDING_ENCODER_TURN_STEPS     =     2      % encoder: amout of steps per turn
        MB_HOLDING_MESSUREMENT_DURATION   =     3      % length of measurement [ms]
        MB_HOLDING_SAMPLE_RATE            =     4      % samplingrate of measurement [Hz]
        MB_HOLDING_STEPER_MICROSTEPS      =     5      % Micosteps from 2^0..2^4
        MB_HOLDING_STEPPER_ACCEL          =     6      % turning acceleration [ω/s²]
        MB_HOLDING_STEPPER_SPEED          =     7      % turning speed [ω/s²]
        MB_HOLDING_STEPPER_TURNS_TO_RUN   =     8      % amount ofsteps to run 0=inf
        MB_NUM_HOLDINS                    =     8

    end

    properties (Access = private, PartialMatchPriority = 2)
        dlgSerialPortApp=0;       % Serial Settings dialog
        obModBusInterface;       % Modbus Object        
        dlModBusSlaveAddr=double(1);     % Modbus clientAddress
        tmModbusTimer=0;         % Timer for polling/writing MonBus data 

%         coils       = zeros(1, MB_NUM_COILS, 'uint16');
%         contacts    = zeros(1, app.MB_NUM_CONTACTS, 'uint16');
%         registers   = zeros(1, app.MB_NUM_INPUTS,  'uint16');
%         holding     = zeros(1, app.MB_NUM_HOLDINS, 'uint16');
        wdCoils       = zeros(1, 30, 'uint16');
        wdContacts    = zeros(1, 30, 'uint16');
        wdRegisters   = zeros(1, 30,  'uint16');
        edHolding     = zeros(1, 30, 'uint16');
        byMutex       = uint8(0); % mutex formodbus communication

        obStreamingInterface;  % Streaming port
        skStreamingData=struct(...
            'timestamp',    [], ...
            'stepperSteps', [], ...
            'ecoderSteps',  [] ...
            )% Data for treaming Result;
        bnIsMessuring=0;       % flag wether a Mesurement is active
    end
    
    properties (Access = public)
        srPortname='none'      % Selected serial port
        byDatabits=uint8(8);    % databits of selected port
        byParity=uint8(8);      % parity of selected port
        byStopbits=uint8(8);    % stopbits of selected port
        byBitrate=uint8(8);     % bitrateof selected port
    end

    methods (Access = public)          
        % Callback function for handling the Status Timer
        function cbModbusTimer(app, event, ~)

            app.wdContacts = read(app.obModBusInterface, 'inputs',1, ...
                app.MB_NUM_CONTACTS, app.dlModBusSlaveAddr );

%           writeModbusData(app, event);
%                 pause (0.1);
%             if ~app.mutex
%                 readModBusData(app, event);
%                 disp(app.mutex);
%             end
        end
        
        function cbReadSerialData(app,src,~)
            bufferStr  = readline(src);
            bufferVect = str2double( split(bufferStr, ';',1 ) );
            app.skStreamingData.timestamp(end+1)    = bufferVect(1);
            app.skStreamingData.stepperSteps(end+1) = bufferVect(2);
            app.skStreamingData.ecoderSteps(end+1)  = bufferVect(3);

            yyaxis(app.UIAxes, 'left');
%             lim(1) = min(app.streamingData.stepperSteps);
%             lim(2) = max(app.streamingData.stepperSteps);
%             app.UIAxes.YLim = lim;
            plot(app.UIAxes,app.skStreamingData.timestamp, ...
                app.skStreamingData.stepperSteps );
%             pause(0.015);
            yyaxis(app.UIAxes, 'right');
%             app.UIAxes.YLim = [min(app.streamingData.ecoderSteps) ...
%                                max(app.streamingData.ecoderSteps)];
            plot(app.UIAxes,app.skStreamingData.timestamp, ...
                app.skStreamingData.ecoderSteps );
%             pause(0.015);
            
        end


    end 
    methods (Access = private)
        
        function readModBusData(app,~)
            % Read all settings from MCU
            % ############################################################
            % #####                     READ COILS                   #####
            % ############################################################
            try
            app.wdCoils = read(app.obModBusInterface, 'coils',1, ...
                app.MB_NUM_COILS, app.dlModBusSlaveAddr );
            catch 
            end
            
            % set Stepper is enabled
            if app.wdCoils(app.MB_COIL_STEPPER_ENA)
                app.StepperEnableSwitch.Value = 'On';
            else
                app.StepperEnableSwitch.Value = 'Off';
            end

            % Set stepper direction
            if app.wdCoils( app.MB_COIL_STEPPER_DIR_CCW )
                app.DirectionSwitch.Value = 'CCW';
            else
                app.DirectionSwitch.Value = 'CW';
            end

            % ############################################################
            % #####                   READ DISCRETE                  #####
            % ############################################################
            app.wdContacts = read(app.obModBusInterface, 'inputs',1, ...
                app.MB_NUM_CONTACTS, app.dlModBusSlaveAddr );

            % set wether the stepper is running
            if app.wdContacts(app.MB_CONTACT_STEPPER_IS_RUNNING)
                app.StartMessureButton.Enable = false;
            else
                app.StartMessureButton.Enable = true;
            end


            % set weter the stepper is running
            if  app.wdContacts(app.MB_CONTACT_STEPPER_IS_STOPPING)
            else
            end

            % read weter the stepper is running
            if  app.wdContacts(app.MB_CONTACT_MESSUREMENT_IS_RUNNING)
                app.StartMessureButton.Enable = false;
                app.ManualRunButton.Enable = false;
            else
                app.StartMessureButton.Enable = true;
                app.ManualRunButton.Enable = true;
            end
return;
            % ############################################################
            % #####                    READ INPUTs                   #####
            % ############################################################
            app.wdRegisters = read(app.obModBusInterface, 'inputregs', 1, ...
                app.MB_NUM_INPUTS, app.dlModBusSlaveAddr, 'uint16');

            % set current Stepper count
            value = uint16([1,4]);
            value = app.wdRegisters(app.MB_INPUT_STEPPER_STEPS: ...
                    app.MB_INPUT_STEPPER_STEPS+3);
            currentStepperPostion = typecast(value,'double');
            currentStepperPostion = swapbytes(currentStepperPostion);
            app.CurrentStepperPostionSpinner.Value = currentStepperPostion;

            % read current Encoder count
            value = app.wdRegisters(app.MB_INPUT_ENCODER_STEPS: ...
                    app.MB_INPUT_ENCODER_STEPS+3);
            currentEncoderPostion = typecast(value,'double');
            currentEncoderPostion = swapbytes(currentEncoderPostion);
            app.CurrentEncoderPostionSpinner.Value = value;

            % read current encoder angle
            value = app.wdRegisters(app.MB_INPUT_ENCODER_STEPS: ...
                    app.MB_INPUT_ENCODER_STEPS+3);
            currentEncoderValue = typecast(currentEncoderValue,'double');
            currentEncoderValue = swapbytes(currentEncoderValue);
            app.CurrentEncoderValueSpinner.Value = value;

            % ############################################################
            % #####                   READ HOLDINGs                  #####
            % ############################################################
            app.edHolding = read(app.obModBusInterface, 'holdingregs', 1, ...
                app.MB_NUM_INPUTS, app.dlModBusSlaveAddr, 'uint16');

            % read Stepper settings
            StepperStepsPerTurn = app.wdRegisters( ...
                app.MB_HOLDING_STEPPER_TURN_STEPS);
            if StepperStepsPerTurn < app.StepperStepsPerTurnSpinner.Limits(1)
                app.StepperStepsPerTurnSpinner.Value = ...
                    app.StepperStepsPerTurnSpinner.Limits(1);
            elseif StepperStepsPerTurn > app.StepperStepsPerTurnSpinner.Limits(2)
                app.StepperStepsPerTurnSpinner.Value = ...
                app.StepperStepsPerTurnSpinner.Limits(2);
            else
                app.StepperStepsPerTurnSpinner.Value = StepperStepsPerTurn;
            end

            % read encoder settings
            EncoderStepsPerTurn = app.wdRegisters( ...
                app.MB_HOLDING_ENCODER_TURN_STEPS);
            if EncoderStepsPerTurn < app.EncoderStepsPerTurnSpinner.Limits(1)
                app.EncoderStepsPerTurnSpinner.Value = ...
                    app.EncoderStepsPerTurnSpinner.Limits(1);
            elseif EncoderStepsPerTurn > app.EncoderStepsPerTurnSpinner.Limits(2)
                app.EncoderStepsPerTurnSpinner.Value = ...
                app.EncoderStepsPerTurnSpinner.Limits(2);
            else
                app.EncoderStepsPerTurnSpinner.Value = EncoderStepsPerTurn;
            end

            % read sampling rate settings
                samplingRate = app.wdRegisters( ...
                app.MB_HOLDING_SAMPLE_RATE);
                if samplingRate < app.SamplingRateSpinner.Limits(1)
                    app.SamplingRateSpinner.Value = ...
                        app.SamplingRateSpinner.Limits(1);
                elseif samplingRate > app.SamplingRateSpinner.Limits(2)
                    app.SamplingRateSpinner.Value = ...
                    app.SamplingRateSpinner.Limits(2);
                else
                    app.SamplingRateSpinner.Value = samplingRate;
                end

            % read Speed rate settings
            speed = app.wdRegisters( ...
                app.MB_HOLDING_STEPPER_SPEED);
            if speed < app.AccelerationRatseSpinner.Limits(1)
                app.AccelerationRatseSpinner.Value = ...
                app.AccelerationRatseSpinner.Limits(1);
            elseif speed > app.AccelerationRatseSpinner.Limits(2)
                app.AccelerationRatseSpinner.Value = ...
                app.AccelerationRatseSpinner.Limits(2);
            else
                app.SpeedRateSpinner.Value = speed;
            end


            % read acceleration settings
            acceleration = app.wdRegisters( ...
                app.MB_HOLDING_STEPPER_ACCEL)/1000;
            if acceleration < app.AccelerationRatseSpinner.Limits(1)
                app.AccelerationRatseSpinner.Value = ...
                app.AccelerationRatseSpinner.Limits(1);
            elseif acceleration > app.AccelerationRatseSpinner.Limits(2)
                app.AccelerationRatseSpinner.Value = ...
                    app.AccelerationRatseSpinner.Limits(2);
            else
                app.AccelerationRatseSpinner.Value = acceleration;
            end

            % read duration of messurement settings    
            duration = app.wdRegisters( ...
                app.MB_HOLDING_MESSUREMENT_DURATION);
            if duration < app.MessurementDrationSpinner.Limits(1)
                app.MessurementDrationSpinner.Value = ...
                    app.MessurementDrationSpinner.Limits(1);
            elseif duration > app.MessurementDrationSpinner.Limits(2)
                app.MessurementDrationSpinner.Value = ...
                app.MessurementDrationSpinner.Limits(2);
            else
                app.MessurementDrationSpinner.Value = duration;
            end

            % read microstepping settins
            if microSteps < min(app.MicrosteppingDropDown.ItemsData)
                app.MicrosteppingDropDown.Value = ...
                    min(app.MicrosteppingDropDown.ItemsData);
            elseif microSteps >  max(app.MicrosteppingDropDown.ItemsData)
                app.MicrosteppingDropDown.Value = ...
                max(app.MicrosteppingDropDown.ItemsData);
            else
                app.MicrosteppingDropDown.ItemsData = microSteps;
            end

            
            % read amount of turns
            stepsToRun = app.wdRegisters(app.MB_HOLDING_STEPPER_TURNS_TO_RUN: ...
                    app.MB_HOLDING_STEPPER_TURN_STEPS+3);
            stepsToRun = typecast(stepsToRun,'uint64');
            stepsToRun = swapbytes(stepsToRun);
            app.StepsToRun.Value = read(app.obModBusInterface, ...
            'holdingregs', app.MB_HOLDING_STEPPER_TURNS_TO_RUN, 1, ...
            app.dlModBusSlaveAddr, 'uint64' );

       end
    
        
        function writeModbusData(app,~)
            % ############################################################
            % #####                    WRITE COILs                   #####
            % ############################################################

            % write state of enable switch
             write(app.obModBusInterface,'coils', 1, ...
                double(app.wdCoils), app.dlModBusSlaveAddr );
return

             app.byMutex = 0;

            % write state of direction switch
%             switch app.DirectionSwitch.Value
%                 case 'CW'
%                 value = 0;
%                 case 'CCW'    
%                 value = 1;
%             end
%             write(app.modBusSlave,'coils',3 ,value, app.modBusSlaveAddr )
            value = double(app.ManualRunButton.Value);
disp(value);
            write(app.obModBusInterface,'coils',4 , value, app.dlModBusSlaveAddr )              

            % ############################################################
            % #####                 WRITE HOLDINGs                   #####
            % ############################################################
%             value = app.MicrosteppingDropDown.Value;
%             write(app.objModBusInterface,'holdingregs',40008 ,value, app.modBusSlaveAddr )

%             value = app.AccelerationRatseSpinner.Value;
%             write(app.objModBusInterface,'holdingregs', 40007 ,value*1000, ...
%                 app.modBusSlaveAddr, 'uint16' )

%             value = app.SpeedRateSpinner.Value;
%             write(app.objModBusInterface,'holdingregs', 40006 ,value, ...
%                 app.modBusSlaveAddr, 'uint16' )

%             value = app.SamplingRateSpinner.Value;
%             write(app.objModBusInterface,'holdingregs', 40005 ,value, ...
%                 app.modBusSlaveAddr, 'uint16' )

%             value = app.MessurementDrationSpinner.Value;
%             write(app.objModBusInterface,'holdingregs', 40004 ,value, ...
%                 app.modBusSlaveAddr, 'uint16' )
%             xlim(app.UIAxes, [0 app.MessurementDrationSpinner.Value])

%             value = app.EncoderStepsPerTurnSpinner.Value;
%             write(app.objModBusInterface,'holdingregs', 40003 ,value, ...
%                 app.modBusSlaveAddr, 'uint16' )  

%             value = app.StepperStepsPerTurnSpinner.Value;
%             write(app.objModBusInterface,'holdingregs', 40002 ,value, ...
%                 app.modBusSlaveAddr, 'uint16' )            
        end
    end        

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.StepperAcceleration.Name = "Stepper acceleration Controller";
            app.obModBusInterface = modbus('serialrtu', app.MB_PORT);
            app.obModBusInterface.BaudRate = 115200;
            app.obModBusInterface.NumRetries = 10;

            app.obStreamingInterface = serialport(app.STREAM_PORT,500000);
            configureTerminator(app.obStreamingInterface,"LF");
            flush(app.obStreamingInterface);
            xlim(app.UIAxes, [0 app.MessurementDrationSpinner.Value]);            
            yyaxis(app.UIAxes, 'left');            
            yyaxis(app.UIAxes, 'right');

            readModBusData(app);

            app.tmModbusTimer = timer(...            
            'ExecutionMode', 'fixedRate', ... % Run timer repeatedly
            'Period', 0.5, ...                % Period is 1 second
            'TasksToExecute', Inf, ...        % run timer forever
            'BusyMode', 'drop');             % 
            app.tmModbusTimer.TimerFcn = @app.cbModbusTimer;  % Callback function
            start(app.tmModbusTimer);
       
            fprintf("INIT DONE\n");
        end

        % Menu selected function: ExitMenu
        function cbMenuExit(app, event)
            selection = uiconfirm(app.StepperAcceleration, 'Quit Application?','Confirm close', 'Icon','question');

            % question "Exit app == true"
            if strcmp(selection, 'OK')
                pause(1);
%                 stop(app.modbusTimer);
%                 delete(app.modBusSlave);
               % configureCallback(app.StreamingInterface,"off")
                app.delete;
            end
            
        end

        % Menu selected function: ModbusInterfaceMenu
        function cbMenuSelectModbusInterface(app, event)
            SerialPortSettings(app,'Modbus Interface');
            fprintf( "Opened %s with %d databits and a data frame of %d, %s, %d\n", ...
            app.srPortname,  ...
            app.byDatabits, ... 
            app.byParity, ...
            app.byStopbits, ...
            app.byBitrate);
            
        end

        % Menu selected function: StreamingInterfaceMenu
        function cbMenuSelectStreamingInterface(app, event)
            SerialPortSettings(app, 'Streaming Interface');
            fprintf( "Opened %s with %d databits and a data frame of %d, %s, %d\n", ...
            app.srPortname,  ...
            app.byDatabits, ... 
            app.byParity, ...
            app.byStopbits, ...
            app.byBitrate);
            
        end

        % Value changed function: ManualRunButton
        function cbBtnManualRun(app, event)

        end

        % Button pushed function: RestEncoderButton
        function cbBtnRestEncoder(app, event)
%             app.readModBusData(app);
if app.byMutex
app.RestEncoderButton.Text = 'Disabled';
else
app.RestEncoderButton.Text = 'Ensabled';
end
% app.mutex = ~app.mutex;
        end

        % Button pushed function: StartMessureButton
        function cbBtnStartMessure(app, event)
            % reads a csv line from StreamingInterface
            configureCallback(app.obStreamingInterface, ...
            "terminator",@(src, event) app.cbReadSerialData(app,src,event) ); 

            % empty all axis from UIAxes
            yyaxis(app.UIAxes, "left")
            cla ( app.UIAxes ); 
            yyaxis(app.UIAxes, "right")
            cla ( app.UIAxes ); 
            
            % empty plottings-tructure
            f=fields( app.skStreamingData ); 
            for i=1:numel(f)
                app.skStreamingData.(f{i})=[];
            end
            
            app.StateLamp.Color = 'red';
            app.ManualRunButton.Enable=false;
            app.StartMessureButton.Enable = false;
            
            write( app.obModBusInterface,'coils', 5 ,1, app.dlModBusSlaveAddr )            
            write( app.obModBusInterface,'holdingregs', 40004 ,...
                app.MessurementDrationSpinner.Value, app.dlModBusSlaveAddr, 'uint16' )
            write( app.obModBusInterface,'holdingregs', 40005 ,...
                app.SamplingRateSpinner.Value, app.dlModBusSlaveAddr, 'uint16' )
        end

        % Close request function: StepperAcceleration
        function cbCloseApplication(app, event)
            cbMenuExit(app, event)            
        end

        % Menu selected function: ClearPlotMenu
        function cmMenuClearPlot(app, event)
            yyaxis(app.UIAxes, "left")
            cla ( app.UIAxes ); 
            yyaxis(app.UIAxes, "right")
            cla ( app.UIAxes ); 
        end

        % Value changed function: StepperEnableSwitch
        function StepperEnableSwitchValueChanged(app, event)
%             app.mutex=1; % disable read from modbus
            switch app.StepperEnableSwitch.Value
                case 'On'
                    write(app.obModBusInterface,'coils', app.MB_COIL_STEPPER_ENA, ...
                    1, app.dlModBusSlaveAddr );
%                    app.test(app.MB_COIL_STEPPER_ENA)= 1;
                case 'Off'    
                    write(app.obModBusInterface,'coils', app.MB_COIL_STEPPER_ENA, ...
                    0, app.dlModBusSlaveAddr );
%                   app.test(app.MB_COIL_STEPPER_ENA)= 0;
%                   disp (app.test([1:10]));
             end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.StepperAcceleration.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {687, 687};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {273, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.StepperAcceleration = uifigure('Visible', 'off');
            app.StepperAcceleration.Name = 'MATLAB App';
            app.StepperAcceleration.CloseRequestFcn = createCallbackFcn(app, @cbCloseApplication, true);
            app.StepperAcceleration.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.StepperAcceleration);
            app.LeftPanel = uipanel(app.GridLayout);
            app.StateLampLabel = uilabel(app.LeftPanel);
            app.StateLampLabel.Text = 'State';
            app.StateLamp = uilamp(app.LeftPanel);
            app.StepperEnableSwitchLabel = uilabel(app.LeftPanel);
            app.StepperEnableSwitchLabel.Text = 'Stepper Enable';
            app.StepperEnableSwitch = uiswitch(app.LeftPanel, 'slider');
            app.StepperEnableSwitch.ValueChangedFcn = createCallbackFcn(app, @StepperEnableSwitchValueChanged, true);
            app.StepperEnableSwitch.Tooltip = {'Stest the steppercoils on'};
            app.DirectionSwitchLabel = uilabel(app.LeftPanel);
            app.DirectionSwitchLabel.Text = 'Direction';
            app.DirectionSwitch = uiswitch(app.LeftPanel, 'slider');
            app.DirectionSwitch.Items = {'CW', 'CCW'};
            app.DirectionSwitch.Tooltip = {'Turn the Stepper clocksise (VW) or counter clockwise (CCW)'};
            app.DirectionSwitch.Value = 'CW';
            app.ManualRunButton = uibutton(app.LeftPanel, 'state');
            app.ManualRunButton.ValueChangedFcn = createCallbackFcn(app, @cbBtnManualRun, true);
            app.ManualRunButton.Tooltip = {'Runs the stepper'};
            app.ManualRunButton.Text = 'Manual Run';
            app.MicrosteppingLabel = uilabel(app.LeftPanel);
            app.MicrosteppingLabel.Text = 'Microstepping:';
            app.MicrosteppingDropDown = uidropdown(app.LeftPanel);
            app.MicrosteppingDropDown.Items = {'Full Step', 'Half Step', 'Quarter Step', '1/8 Step', '1/6 Step', '1/32 Step'};
            app.MicrosteppingDropDown.ItemsData = [0 1 2 3 4 7];
            app.MicrosteppingDropDown.Tooltip = {'Dividin the stepper Frequency (see speed)'};
            app.MicrosteppingDropDown.Value = 0;
            app.StartMessureButton = uibutton(app.LeftPanel, 'push');
            app.StartMessureButton.ButtonPushedFcn = createCallbackFcn(app, @cbBtnStartMessure, true);
            app.StartMessureButton.Text = 'Start Messure';
            app.MessureTimemsLabel = uilabel(app.LeftPanel);
            app.MessureTimemsLabel.Text = 'Messuremnt dur. [ms]:';
            app.MessurementDrationSpinner = uispinner(app.LeftPanel);
            app.MessurementDrationSpinner.Step = 10;
            app.MessurementDrationSpinner.Limits = [0 10000];
            app.MessurementDrationSpinner.RoundFractionalValues = 'on';
            app.MessurementDrationSpinner.ValueDisplayFormat = '%.0f';
            app.MessurementDrationSpinner.Tooltip = {'Duration of messurement [ms]'};
            app.MessurementDrationSpinner.Value = 500;
            app.SamplingratemsLabel = uilabel(app.LeftPanel);
            app.SamplingratemsLabel.Text = 'Samplingrate [ms]:';
            app.SamplingRateSpinner = uispinner(app.LeftPanel);
            app.SamplingRateSpinner.Limits = [1 50000];
            app.SamplingRateSpinner.RoundFractionalValues = 'on';
            app.SamplingRateSpinner.ValueDisplayFormat = '%.0f';
            app.SamplingRateSpinner.Tooltip = {'Samplingrate [Hz]'};
            app.SamplingRateSpinner.Value = 10;
            app.AccelerationradminLabel = uilabel(app.LeftPanel);
            app.AccelerationradminLabel.Text = 'Acceleration [ω/s²]:';
            app.AccelerationRatseSpinner = uispinner(app.LeftPanel);
            app.AccelerationRatseSpinner.Step = 0.1;
            app.AccelerationRatseSpinner.Limits = [0 Inf];
            app.AccelerationRatseSpinner.Tooltip = {'Acceleration []'};
            app.AccelerationRatseSpinner.Value = 1.5613;
            app.SpeedradminLabel = uilabel(app.LeftPanel);
            app.SpeedradminLabel.Text = 'Speed [ω/s]: ';
            app.SpeedRateSpinner = uispinner(app.LeftPanel);
            app.SpeedRateSpinner.Limits = [0 500];
            app.SpeedRateSpinner.RoundFractionalValues = 'on';
            app.SpeedRateSpinner.ValueDisplayFormat = '%.0f';
            app.SpeedRateSpinner.Tooltip = {'Sete the speed (rps)'};
            app.SpeedRateSpinner.Value = 300;
            app.RestEncoderButton = uibutton(app.LeftPanel, 'push');
            app.RestEncoderButton.ButtonPushedFcn = createCallbackFcn(app, @cbBtnRestEncoder, true);
            app.RestEncoderButton.Text = 'Rest Encoder';
            app.SpeedradminLabel_2 = uilabel(app.LeftPanel);
            app.SpeedradminLabel_2.Text = 'Amount of turns:';
            app.StepsToRun = uispinner(app.LeftPanel);
            app.StepsToRun.Limits = [0 Inf];
            app.StepsToRun.RoundFractionalValues = 'on';
            app.StepsToRun.ValueDisplayFormat = '%.0f';
            app.StepsToRun.Tooltip = {'Amount of turns to run 0=inf'};
            app.RightPanel = uipanel(app.GridLayout);
            app.TabGroup = uitabgroup(app.RightPanel);
            app.PlotTab = uitab(app.TabGroup);
            app.PlotTab.Title = 'Plot';
            app.PlotPanel = uipanel(app.PlotTab);
            app.PlotPanel.Title = 'Plot';
            app.UIAxes = uiaxes(app.PlotPanel);
            title(app.UIAxes, 'Lost Steps Validation')
            xlabel(app.UIAxes, 'Time')
            ylabel(app.UIAxes, 'Steps')
            zlabel(app.UIAxes, 'Z')
            subtitle(app.UIAxes, 'Stepper advised vs. steps count')
            app.CurrentStepperPostionSpinnerLabel = uilabel(app.PlotTab);
            app.CurrentStepperPostionSpinnerLabel.Text = 'Current Stepper Postion:';
            app.CurrentStepperPostionSpinner = uispinner(app.PlotTab);
            app.CurrentStepperPostionSpinner.Editable = 'off';
            app.CurrentEncoderValueSpinnerLabel = uilabel(app.PlotTab);
            app.CurrentEncoderValueSpinnerLabel.Text = 'Current Encoder Value:';
            app.CurrentEncoderValueSpinner = uispinner(app.PlotTab);
            app.CurrentEncoderValueSpinner.Editable = 'off';
            app.RemaingTimeforMessureSpinnerLabel = uilabel(app.PlotTab);
            app.RemaingTimeforMessureSpinnerLabel.Text = 'Remaing Time for Messure:';
            app.RemaingTimeforMessureSpinner = uispinner(app.PlotTab);
            app.RemaingTimeforMessureSpinner.Editable = 'off';
            app.CurrentEncoderPostionSpinner_2Label_2 = uilabel(app.PlotTab);
            app.CurrentEncoderPostionSpinner_2Label_2.Text = 'Current Encoder Postion:';
            app.CurrentEncoderPostionSpinner = uispinner(app.PlotTab);
            app.CurrentEncoderPostionSpinner.Editable = 'off';
            app.SettingsTab = uitab(app.TabGroup);
            app.SettingsTab.Title = 'Settings';
            app.GridLayout2 = uigridlayout(app.SettingsTab);
            app.EncoderstepersperTurnLabel = uilabel(app.GridLayout2);
            app.EncoderstepersperTurnLabel.Text = 'Encoder, steps per Turn';
            app.EncoderStepsPerTurnSpinner = uispinner(app.GridLayout2);
            app.EncoderStepsPerTurnSpinner.Limits = [0 Inf];
            app.EncoderStepsPerTurnSpinner.Value = 400;
            app.StepperstepersperTurnLabel = uilabel(app.GridLayout2);
            app.StepperstepersperTurnLabel.Text = 'Stepper, steps per Turn';
            app.StepperStepsPerTurnSpinner = uispinner(app.GridLayout2);
            app.StepperStepsPerTurnSpinner.Limits = [0 Inf];
            app.StepperStepsPerTurnSpinner.Value = 200;
            app.FileMenu = uimenu(app.StepperAcceleration);
            app.FileMenu.Text = '&File';
            app.ModbusInterfaceMenu = uimenu(app.FileMenu);
            app.ModbusInterfaceMenu.MenuSelectedFcn = createCallbackFcn(app, @cbMenuSelectModbusInterface, true);
            app.ModbusInterfaceMenu.Accelerator = 'M';
            app.ModbusInterfaceMenu.Text = 'Mod&bus Interface';
            app.StreamingInterfaceMenu = uimenu(app.FileMenu);
            app.StreamingInterfaceMenu.MenuSelectedFcn = createCallbackFcn(app, @cbMenuSelectStreamingInterface, true);
            app.StreamingInterfaceMenu.Accelerator = 'T';
            app.StreamingInterfaceMenu.Text = '&Streaming Interface';
            app.ExportCXVMenu = uimenu(app.FileMenu);
            app.ExportCXVMenu.Enable = 'off';
            app.ExportCXVMenu.Accelerator = 'S';
            app.ExportCXVMenu.Text = 'E&xport CXV';
            app.ImportCSVMenu = uimenu(app.FileMenu);
            app.ImportCSVMenu.Enable = 'off';
            app.ImportCSVMenu.Separator = 'on';
            app.ImportCSVMenu.Accelerator = 'I';
            app.ImportCSVMenu.Text = '&Import CSV';
            app.ExitMenu = uimenu(app.FileMenu);
            app.ExitMenu.MenuSelectedFcn = createCallbackFcn(app, @cbMenuExit, true);
            app.ExitMenu.Separator = 'on';
            app.ExitMenu.Accelerator = 'Q';
            app.ExitMenu.Text = '&Exit';
            app.EditMenu = uimenu(app.StepperAcceleration);
            app.EditMenu.Text = '&Edit';
            app.ClearPlotMenu = uimenu(app.EditMenu);
            app.ClearPlotMenu.MenuSelectedFcn = createCallbackFcn(app, @cmMenuClearPlot, true);
            app.ClearPlotMenu.Text = 'Clear Plot';
            % Show the figure after all components are created
            app.StepperAcceleration.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AccelController

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.StepperAcceleration)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.StepperAcceleration)
        end
    end
end