classdef SerialPortSettings < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        OKButton                matlab.ui.control.Button
        CancelButton            matlab.ui.control.Button
        DatabitsButtonGroup     matlab.ui.container.ButtonGroup
        dataBit_6               matlab.ui.control.RadioButton
        dataBit_7               matlab.ui.control.RadioButton
        dataBit_8               matlab.ui.control.RadioButton
        BitrateDropDown         matlab.ui.control.DropDown
        BitrateDropDownLabel    matlab.ui.control.Label
        StopbitsButtonGroup     matlab.ui.container.ButtonGroup
        stopBit_2               matlab.ui.control.RadioButton
        stopBit_15              matlab.ui.control.RadioButton
        stopBit_1               matlab.ui.control.RadioButton
        PatityButtonGroup       matlab.ui.container.ButtonGroup
        parityNoneButton        matlab.ui.control.RadioButton
        parityOddButton         matlab.ui.control.RadioButton
        patityEvenButton        matlab.ui.control.RadioButton
        InterfaceDropDown       matlab.ui.control.DropDown
        InterfaceDropDownLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        hwndCalleeApp  % Handler of the main app
        serial_ports % List of serial ports
        windowTitle  % Description
    end
    
    properties (Access = public)
%         portname   % Selected serial port
%         databits;  % parity of selected port
%         parity     % parity of selected port
%         stopbits;   % parity of selected port
%         bitrate;   % parity of selected port
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, hwnd, windowTitle)
            app.windowTitle = windowTitle;
            app.hwndCalleeApp = hwnd;
            app.UIFigure.Name = windowTitle;
            app.serial_ports = serialportlist("available");
            app.InterfaceDropDown.Items = app.serial_ports;
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            app.hwndCalleeApp.portname = app.InterfaceDropDown.Value;
            app.hwndCalleeApp.bitrate  = str2double(app.BitrateDropDown.Value);
            app.hwndCalleeApp.databits = str2double(app.DatabitsButtonGroup.SelectedObject.Text);
            app.hwndCalleeApp.parity   = app.PatityButtonGroup.SelectedObject.Text;
            app.hwndCalleeApp.stopbits = str2double(app.StopbitsButtonGroup.SelectedObject.Text);
%             if app.UIFigure.Name(1)=='B'
%                 fprintf("CommanndPort\n");
%                 app.hwndMainApp.CommanndPort = serialport(app.InterfaceDropDown.Value,...
%                     str2double(app.BitrateDropDown.Value) );
%                 app.hwndMainApp.CommanndPort.DataBits = app.DatabitsButtonGroup.SelectedObject.Text;
%                 app.hwndMainApp.CommanndPort.Parity = app.PatityButtonGroup.SelectedObject.Text;
%                 app.hwndMainApp.CommanndPort.StopBits = app.StopbitsButtonGroup.SelectedObject.Text;
%             else    
%                 fprintf("StreamingPort\n");
%                 app.hwndMainApp.StreamingPort = serialport(app.InterfaceDropDown.Value,...
%                     str2double(app.BitrateDropDown.Value) );
%                 app.hwndMainApp.StreamingPort.DataBits = app.DatabitsButtonGroup.SelectedObject.Text;
%                 app.hwndMainApp.StreamingPort.Parity = app.PatityButtonGroup.SelectedObject.Text;
%                 app.hwndMainApp.StreamingPort.StopBits = app.StopbitsButtonGroup.SelectedObject.Text;
%             end
        app.delete;
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            app.delete;
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.delete;
            
        end

        % Value changed function: InterfaceDropDown
        function InterfaceDropDownValueChanged(app, event)
            value = app.InterfaceDropDown.Value;
            app.UIFigure.Name = strcat(app.windowTitle, ' - ', value);
        end

        % Selection changed function: DatabitsButtonGroup
        function DatabitsButtonGroupSelectionChanged(app, event)
            selectedButton = app.DatabitsButtonGroup.SelectedObject;
            selectedButton.Text;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.InterfaceDropDownLabel = uilabel(app.UIFigure);
            app.InterfaceDropDownLabel.Text = 'Interface:';
            app.InterfaceDropDown = uidropdown(app.UIFigure);
            app.InterfaceDropDown.ValueChangedFcn = createCallbackFcn(app, @InterfaceDropDownValueChanged, true);
            app.PatityButtonGroup = uibuttongroup(app.UIFigure);
            app.PatityButtonGroup.Title = 'Patity:';
            app.patityEvenButton = uiradiobutton(app.PatityButtonGroup);
            app.patityEvenButton.Text = 'even';
            app.parityOddButton = uiradiobutton(app.PatityButtonGroup);
            app.parityOddButton.Text = 'odd';
            app.parityNoneButton = uiradiobutton(app.PatityButtonGroup);
            app.parityNoneButton.Text = 'none';
            app.parityNoneButton.Value = true;
            app.StopbitsButtonGroup = uibuttongroup(app.UIFigure);
            app.StopbitsButtonGroup.Title = 'Stopbits:';
            app.stopBit_1 = uiradiobutton(app.StopbitsButtonGroup);
            app.stopBit_1.Text = '1';
            app.stopBit_1.Value = true;
            app.stopBit_15 = uiradiobutton(app.StopbitsButtonGroup);
            app.stopBit_15.Text = '1.5';
            app.stopBit_2 = uiradiobutton(app.StopbitsButtonGroup);
            app.stopBit_2.Text = '2';
            app.BitrateDropDownLabel = uilabel(app.UIFigure);
            app.BitrateDropDownLabel.Text = 'Bitrate';
            app.BitrateDropDown = uidropdown(app.UIFigure);
            app.BitrateDropDown.Items = {'9600', '19200', '38400', '57600', '115200', '230400', '460800', '500000', ''};
            app.BitrateDropDown.ItemsData = {'9600', '19200', '38400', '57600', '115200', '230400', '460800', '500000', ''};
            app.BitrateDropDown.Value = '115200';
            app.DatabitsButtonGroup = uibuttongroup(app.UIFigure);
            app.DatabitsButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @DatabitsButtonGroupSelectionChanged, true);
            app.DatabitsButtonGroup.Title = 'Databits:';
            app.dataBit_8 = uiradiobutton(app.DatabitsButtonGroup);
            app.dataBit_8.Text = '6';
            app.dataBit_7 = uiradiobutton(app.DatabitsButtonGroup);
            app.dataBit_7.Text = '7';
            app.dataBit_6 = uiradiobutton(app.DatabitsButtonGroup);
            app.dataBit_6.Text = '8';
            app.dataBit_6.Value = true;
            app.CancelButton = uibutton(app.UIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.OKButton = uibutton(app.UIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SerialPortSettings(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end